classdef BrainViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
        PatientsMenu                   matlab.ui.container.Menu
        SliceMenu                      matlab.ui.container.Menu
        SurfaceMenu                    matlab.ui.container.Menu
        LoadfromSurfaceFileMenu        matlab.ui.container.Menu
        GeneratefromLabeledVolumeMenu  matlab.ui.container.Menu
        NetworkMenu                    matlab.ui.container.Menu
        StreamlinesMenu                matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        STLMenu                        matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        ImageSettingsMenu              matlab.ui.container.Menu
        AtlasMenu                      matlab.ui.container.Menu
        MovieMakerMenu                 matlab.ui.container.Menu
        ClearViewerMenu                matlab.ui.container.Menu
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        subj
        subjFolders
        baseFsFolderpath
        iELVisToolboxPath = ''
        loadList
        Elecs
        SliceNames
        Rotate3DOn = false;
        Toolbar
        AxesLink
        Network
        SliceAtlas

        MainApp
    end

    properties (Access = public)
        RotateParameters
        Light
        Slice
        fsfolder
        currentFolder = userpath;
        SettingsApp
        AtlasApp
        AtlasData
        MovieApp
        ElectrodesHandle
        SurfacesHandle
        SlicesHandle
        StreamlinesHandle
        NetworksHandle
        AlphaMask
        ColormapName = 'gray';
        CustomColormap
    end

    methods (Access = private)

        function getIELVISpath(app)
            appPath = fileparts(mfilename('fullpath'));
            configPath = fullfile(appPath,"utils");
            if isfile(fullfile(configPath,"iELVisPath.txt"))
                fid = fopen(fullfile(configPath,'iELVisPath.txt'),'r');
                app.iELVisToolboxPath = fscanf(fid,'%s');
                fclose(fid);
                if isempty(app.iELVisToolboxPath)
                    setupIELVisPath(app)
                else
                    addpath(genpath(app.iELVisToolboxPath));
                end
            else
                setupIELVisPath(app)
            end

        end

        function setupIELVisPath(app)
            appPath = fileparts(mfilename('fullpath'));
            configPath = fullfile(appPath,"utils");
            response = uiconfirm(app.UIFigure,{'The BrainViewer App requires the use of the iELVis toolbox to operate properly, which can be found on GitHub.',...
                'Options:',...
                '''Download iELVis Toolbox'' <- Download the toolbox to a destination of your choosing. Use this option if you do not have the iELVis toolbox or you want a copy for this app in a different location',...
                '''Use existing iELVis Toolbox'' will allow you to select the location of the toolbox within your system',...
                'NOTE: For the above two options, the path of the toolbox will be stored for future use',...
                '''Quit'' <- Do nothing and close this application'},...
                'Application Setup',...
                'Options',{'Download iELVis Toolbox','Use existing iELVis Toolbox','Quit'});

            switch response
                case 'Download iELVis Toolbox'
                    d = uiprogressdlg(app.UIFigure,'Indeterminate','on','Message','Installing iELVis Toolbox');
                    githubLink = fullfile('https://github.com/iELVis/iELVis','archive','refs','heads','master.zip');
                    selectedPath = uigetdir(appPath,'Select folder to store iELVis Toolbox');
                    if selectedPath == 0
                        close(d)
                        click = uiconfirm(app.UIFigure,'No destination was selected. Closing Application.','Destination Not Selected','Options','OK');
                        UIFigureCloseRequest(app)
                        return
                    end
                    zippedToolboxPath = fullfile(selectedPath,'iELVis.zip');
                    unzippedToolboxPath = fullfile(selectedPath,'iELVis');
                    websave(zippedToolboxPath,githubLink);
                    unzip(zippedToolboxPath,unzippedToolboxPath)
                    delete(zippedToolboxPath);
                    fid = fopen(fullfile(configPath,'iELVisPath.txt'),'w');
                    fprintf(fid,'%s',fullfile(unzippedToolboxPath));
                    fclose(fid);
                    app.iELVisToolboxPath = unzippedToolboxPath;
                    addpath(genpath(app.iELVisToolboxPath));
                    close(d)
                case 'Use existing iELVis Toolbox'
                    selectedPath = uigetdir(appPath,'Select iELVis Toolbox folder');
                    if selectedPath == 0
                        click = uiconfirm(app.UIFigure,'No folder was selected. Closing Application.','No Folder Selected','Options','OK');
                        UIFigureCloseRequest(app)
                        return
                    end
                    fid = fopen(fullfile(configPath,'iELVisPath.txt'),'w');
                    fprintf(fid,'%s',fullfile(selectedPath));
                    fclose(fid);
                    app.iELVisToolboxPath = selectedPath;
                    addpath(genpath(app.iELVisToolboxPath));
                case 'Quit'
                    UIFigureCloseRequest(app)
                    return
            end
        end

        function selectImagingFolder(app)
            tmp = uigetfilesfolders(app.fsfolder,'DIRECTORIES_ONLY','Select Patient Datafile(s)');
            figure(app.UIFigure);
            if isempty(tmp)
                return
            end
            if numel(tmp)>1
                selection = uiconfirm(app.UIFigure,'Multiple patient folders selected. Patient electrodes will be mapped to MNI305 template brain. Continue?','Multiple Patients Selected','Options',{'Yes','No'});
                switch selection
                    case 'Yes'
                        templateFolder = [];
                        for i = 1:numel(tmp)
                            if exist(fullfile(tmp(i).folder,tmp(i).name,'IMG','fsaverage'),'dir')
                                templateFolder = fullfile(tmp(i).folder,tmp(i).name,'IMG','fsaverage');
                                break
                            end
                        end
                        if isempty(templateFolder)
                            selection = uiconfirm(app.UIFigure,'None of the selected patients contain the MNI305 template brain. Would you like to select one manually','Template Brain Not Found','Options',{'Yes','No'});
                            switch selection
                                case 'Yes'
                                    tmpTemplate = uigetfilesfolders(app.fsfolder,'DIRECTORIES_ONLY','Select MNI305 Template Brain');
                                    templateFolder = fullfile(tmpTemplate.folder,tmpTemplate.name);
                                case 'No'
                                    return
                            end
                        end
                        app.fsfolder = templateFolder;
                        createDatasetListOptions(app)
                        for i = 1:numel(tmp)
                            generateElectrodesFromSubject(app,fullfile(tmp(i).folder,tmp(i).name))
                        end
                    case 'No'
                end
                return
            end

            reg = regexp(tmp.name,'(?<subj>\w+)Datafile','names');
            if isempty(reg)
                uialert(app.UIFigure,'Incorrect Folder Selected','Wrong Folder')
                return
            end
            app.fsfolder = fullfile(tmp.folder,tmp.name,'IMG',reg.subj);
            createDatasetListOptions(app)
        end

        function createDatasetListOptions(app)
            pathparts = strsplit(app.fsfolder,filesep);
            app.subj = pathparts{numel(pathparts)};
            app.UIAxes.Title.String = app.subj;
            list = {};
            if exist(fullfile(app.fsfolder,'elec_recon',[app.subj '.LEPTO']),'file')
                list = [list {'Electrode Recon'}];
                if ~isempty(app.MainApp)
                    list = [list {'Connectivity Network'}];
                end
            end
            if exist(fullfile(app.fsfolder,'surf','lh.pial'),'file')
                list = [list {'LH Pial Surface'}];
            elseif exist(fullfile(app.fsfolder,'surf','lh.pial.T1'),'file')
                list = [list {'LH T1 Pial Surface'}];
            end
            if exist(fullfile(app.fsfolder,'surf','rh.pial'),'file')
                list = [list {'RH Pial Surface'}];
            elseif exist(fullfile(app.fsfolder,'surf','rh.pial.T1'),'file')
                list = [list {'RH T1 Pial Surface'}];
            end
            if exist(fullfile(app.fsfolder,'surf','lh.white'),'file')
                list = [list {'LH White Matter Surface'}];
            elseif exist(fullfile(app.fsfolder,'surf','lh.white.T1'),'file')
                list = [list {'LH T1 White Matter Surface'}];
            end
            if exist(fullfile(app.fsfolder,'surf','rh.white'),'file')
                list = [list {'RH White Matter Surface'}];
            elseif exist(fullfile(app.fsfolder,'surf','rh.white.T1'),'file')
                list = [list {'RH T1 White Matter Surface'}];
            end
            if exist(fullfile(app.fsfolder,'mri','T1.mgz'),'file')
                list = [list {'T1 MRI'}];
            end
            if exist(fullfile(app.fsfolder,'mri','aparc+aseg.mgz'),'file')
                list = [list {'Labeled MRI'}];
            end
            if exist(fullfile(app.fsfolder,'elec_recon','postInPre.nii.gz'),'file') || exist(fullfile(app.fsfolder,'elec_recon','ctINt1.nii.gz'),'file')
                list = [list {'CT'}];
            end

            [idx,tf] = listdlg('ListString',list,'PromptString',{'Select datasets to load'},'SelectionMode','multiple','OKString','Load');
            figure(app.UIFigure);
            if tf==0
                return
            else
                app.loadList = list(idx);
                resetViewer(app)
                loadDatasets(app)
            end

        end

        function resetViewer(app)
            if ~isempty(app.SettingsApp) && isvalid(app.SettingsApp)
                delete(app.SettingsApp)
                app.SettingsApp = [];
            end
            if ~isempty(app.AtlasApp) && isvalid(app.AtlasApp)
                delete(app.AtlasApp)
                app.AtlasApp = [];
            end
            if ~isempty(app.MovieApp) && isvalid(app.MovieApp)
                delete(app.MovieApp)
                app.MovieApp = [];
            end
            delete(app.UIAxes.Children)
            delete(app.UIAxes2.Children)
            colormap(app.UIAxes,'gray')
            app.SliceNames = [];
            app.ElectrodesHandle = [];
            app.SurfacesHandle = [];
            app.SlicesHandle = [];
            app.AtlasData = [];
            app.AtlasMenu.Enable = 'off';
            app.AlphaMask = [];
            app.NetworksHandle = [];
            app.RotateParameters = [];
            app.STLMenu.Enable = 'off';
        end

        function loadDatasets(app)
            app.UIFigure.Pointer = 'watch';
            drawnow
            createOrientationAxes(app);
            for i = 1:numel(app.loadList)
                switch app.loadList{i}
                    case 'Electrode Recon'
                        generateElectrodesAsProbesFromSubject(app)
                    case 'Connectivity Network'
                        switch app.MainApp.UIFigure.Name
                            case 'Epoch Viewer'
                                loadNetwork(app,app.MainApp.MarkerTable)
                            case 'Stim Atlas'
                                overlayNetwork(app)
                        end
                    case 'LH Pial Surface'
                        surfFile = fullfile(app.fsfolder,'surf','lh.pial');
                        generateSurfaceFromSurfaceFile(app,surfFile)
                    case 'LH T1 Pial Surface'
                        surfFile = fullfile(app.fsfolder,'surf','lh.pial.T1');
                        generateSurfaceFromSurfaceFile(app,surfFile)
                    case 'RH Pial Surface'
                        surfFile = fullfile(app.fsfolder,'surf','rh.pial');
                        generateSurfaceFromSurfaceFile(app,surfFile)
                    case 'RH T1 Pial Surface'
                        surfFile = fullfile(app.fsfolder,'surf','rh.pial.T1');
                        generateSurfaceFromSurfaceFile(app,surfFile)
                    case 'LH White Matter Surface'
                        surfFile = fullfile(app.fsfolder,'surf','lh.white');
                        generateSurfaceFromSurfaceFile(app,surfFile)
                    case 'LH T1 White Matter Surface'
                        surfFile = fullfile(app.fsfolder,'surf','lh.white.T1');
                        generateSurfaceFromSurfaceFile(app,surfFile)
                    case 'RH White Matter Surface'
                        surfFile = fullfile(app.fsfolder,'surf','rh.white');
                        generateSurfaceFromSurfaceFile(app,surfFile)
                    case 'RH T1 White Matter Surface'
                        surfFile = fullfile(app.fsfolder,'surf','rh.white.T1');
                        generateSurfaceFromSurfaceFile(app,surfFile)
                    case 'T1 MRI'
                        sliceFile = fullfile(app.fsfolder,'mri','T1.mgz');
                        generateSlicesFromVolumeFile(app,sliceFile)
                    case 'Labeled MRI'
                        sliceFile = fullfile(app.fsfolder,'mri','aparc+aseg.mgz');
                        generateSlicesFromVolumeFile(app,sliceFile)
                    case 'CT'
                        d = dir(fullfile(app.fsfolder,'elec_recon'));
                        if any(strcmp({d.name},'postInPre.nii.gz'))
                            sliceFile = fullfile(app.fsfolder,'elec_recon','postInPre.nii.gz');
                        elseif any(strcmp({d.name},'ctINt1.nii.gz'))
                            sliceFile = fullfile(app.fsfolder,'elec_recon','ctINt1.nii.gz');
                        end
                        generateSlicesFromVolumeFile(app,sliceFile)
                end
            end
            [app.RotateParameters.Azimuth,app.RotateParameters.Elevation] = view(app.UIAxes);
            app.UIFigure.Pointer = 'arrow';
        end

        function createOrientationAxes(app)
            plot3(app.UIAxes2,[0 1],[0 0],[0 0],'LineWidth',2,'Color',[1 0 0])
            plot3(app.UIAxes2,[0 0],[0 1],[0 0],'LineWidth',2,'Color',[0 1 0])
            plot3(app.UIAxes2,[0 0],[0 0],[0 1],'LineWidth',2,'Color',[0 0 1])
            xlim(app.UIAxes2,[-1 1])
            ylim(app.UIAxes2,[-1 1])
            zlim(app.UIAxes2,[-1 1])
        end

        function generateElectrodesAsProbesFromSubject(app)
            %Get Subject-spaced Electrode Coordinates
            file = fullfile(app.fsfolder,'elec_recon',[app.subj '.LEPTO']);
            elecCoords = readmatrix(file,'FileType','text','NumHeaderLines',2);

            %Get Labels of Electrode Coordinates
            file = fullfile(app.fsfolder,'elec_recon',[app.subj '.electrodeNames']);
            montage = readtable(file,'FileType','text','NumHeaderLines',1,'VariableNamingRule','preserve');
            elecLabels = montage{:,1};
            
            %Zero-pad Numbers at end of Electrode Labels
            tmp = regexp(elecLabels,'\d+$','match');
            tmp = vertcat(tmp{:});
            tmp = cellfun(@str2num,tmp,'Uni',0);
            tmp = cellfun(@(x) num2str(x,'%02.f'),tmp,'Uni',0);
            tmp1 = cellfun(@(x,y,z) regexprep(x,y,z),elecLabels,repmat({'\d+$'},length(elecLabels),1),tmp,'Uni',0);

            %Sort Labels/Coordinates using new zero-padded labels
            [app.Elecs.labels,sortingIdx] = sort(tmp1);
            app.Elecs.coords = elecCoords(sortingIdx,:);
            
            %Get General Label of Each sEEG Probe (no contact index)
            rootLabels = regexprep(app.Elecs.labels,'-*\d+$','');
            rootLabels = regexprep(rootLabels,'-','');

            [probeLabels,ia,ib] = unique(rootLabels);
            nUniqueLabels = numel(probeLabels);
            app.Elecs.cmap = hsv(nUniqueLabels);
            
            %Generate Figure/Object Handles
            probeIdxLimit = [ia [ia(2:numel(ia))-1;length(ib)]];
            for nn=1:nUniqueLabels
                app.ElectrodesHandle.(probeLabels{nn}) = struct;
                app.ElectrodesHandle.(probeLabels{nn}).Scatter = scatter3(app.UIAxes,app.Elecs.coords(ib==nn,1),app.Elecs.coords(ib==nn,2),app.Elecs.coords(ib==nn,3),100,app.Elecs.cmap(nn,:),'filled');
                app.ElectrodesHandle.(probeLabels{nn}).Line = plot3(app.UIAxes,app.Elecs.coords(probeIdxLimit(nn,:),1),app.Elecs.coords(probeIdxLimit(nn,:),2),app.Elecs.coords(probeIdxLimit(nn,:),3),'Color',app.Elecs.cmap(nn,:),'LineWidth',3,'HitTest',0);
                app.ElectrodesHandle.(probeLabels{nn}).Text = text(app.UIAxes,2+app.Elecs.coords(probeIdxLimit(nn,2),1),app.Elecs.coords(probeIdxLimit(nn,2),2),2+app.Elecs.coords(probeIdxLimit(nn,2),3), probeLabels{nn}, 'Color',app.Elecs.cmap(nn,:),'HitTest',0);
            end
        end

        function generateElectrodesFromSubject(app,path)
            %Get SubjectID
            tmp = regexp(path,'(?<subj>\w+)Datafile','names');
            subjectID = tmp.subj;
            
            %Get MNI-Spaced Electrode Coordinates
            file = fullfile(path,'IMG',subjectID,'elec_recon',[subjectID,'.FSAVERAGE']);
            if ~exist(file,'file')
                uialert(app.UIFigure,['FSAVERAGE file for ',subjectID,' does not exist!'],'Missing File');
                return
            end
            elecCoords = readmatrix(file,'FileType','text','NumHeaderLines',2);

            %Get Labels of Electrode Coordinates
            file = fullfile(path,'IMG',subjectID,'elec_recon',[subjectID '.electrodeNames']);
            montage = readtable(file,'FileType','text','NumHeaderLines',1,'VariableNamingRule','preserve');
            elecsLabels = montage{:,1};

            %Zero-pad Numbers at end of Electrode Labels
            tmp = regexp(elecsLabels,'\d+$','match');
            tmp = vertcat(tmp{:});
            tmp = cellfun(@str2num,tmp,'Uni',0);
            tmp = cellfun(@(x) num2str(x,'%02.f'),tmp,'Uni',0);
            tmp1 = cellfun(@(x,y,z) regexprep(x,y,z),elecsLabels,repmat({'\d+$'},length(elecsLabels),1),tmp,'Uni',0);

            %Sort Labels/Coordinates using new zero-padded labels
            [elecsLabels,sortingIdx] = sort(tmp1);
            elecCoords = elecCoords(sortingIdx,:);
            
            %Get General Label of Each sEEG Probe (no contact index)
            rootLabels = regexprep(elecsLabels,'-*\d+$','');
            rootLabels = regexprep(rootLabels,'-','');
            
            %Generate Line Plot Inputs
            [~,ia,ib] = unique(rootLabels);
            probeIdxLimit = [ia [ia(2:numel(ia))-1;length(ib)]];
            tmp = elecCoords(probeIdxLimit,1);
            tmp = reshape(tmp,size(probeIdxLimit));
            tmp = [tmp nan(size(tmp,1),1)]';
            lineCoordsX = tmp(:);
            tmp = elecCoords(probeIdxLimit,2);
            tmp = reshape(tmp,size(probeIdxLimit));
            tmp = [tmp nan(size(tmp,1),1)]';
            lineCoordsY = tmp(:);
            tmp = elecCoords(probeIdxLimit,3);
            tmp = reshape(tmp,size(probeIdxLimit));
            tmp = [tmp nan(size(tmp,1),1)]';
            lineCoordsZ = tmp(:);
            
            % Generate Figure/Object Handles
            app.ElectrodesHandle.(subjectID) = struct;
            app.ElectrodesHandle.(subjectID).Scatter = scatter3(app.UIAxes,elecCoords(:,1),elecCoords(:,2),elecCoords(:,3),100,'filled');
            color = app.ElectrodesHandle.(subjectID).Scatter.CData;
            app.ElectrodesHandle.(subjectID).Line = plot3(app.UIAxes,lineCoordsX,lineCoordsY,lineCoordsZ,'Color',color,'LineWidth',3,'HitTest',0);
            app.ElectrodesHandle.(subjectID).Text = '';            
        end

        function generateSurfaceFromSurfaceFile(app,file)
            % Get filename
            [~,filename,ext] = fileparts(file);

            %Generate Structure Name
            subStructName = makeValidStructureNameFromFilename(app,[filename,ext],app.SurfacesHandle);
            subStructName = regexprep(subStructName,'T1$','');

            %Get Surface Variables
            [vertices,faces]=read_surf(file); %<-iELVis Function
            if min(min(faces))<1
                faces = faces+1;
            end

            %Generate/Modify Figure/Object Handles
            app.SurfacesHandle.(subStructName) = trisurf(faces,vertices(:,1),vertices(:,2),vertices(:,3),'Parent',app.UIAxes);
            app.SurfacesHandle.(subStructName).EdgeAlpha = 0;
            app.SurfacesHandle.(subStructName).PickableParts = 'visible';
            app.SurfacesHandle.(subStructName).HitTest = ~app.Rotate3DOn;
            if contains(subStructName,'pial')
                app.SurfacesHandle.(subStructName).FaceColor = [1 1 1]*0.7;
            elseif contains(subStructName,'white')
                app.SurfacesHandle.(subStructName).FaceColor = [1 1 1]*0.9;
            else
                app.SurfacesHandle.(subStructName).FaceColor = [242 174 177]./256;
            end

            %Generate/Modify Lighting Object of UIAxes
            if ~any(isgraphics(app.UIAxes.Children,'Light'))
                view(app.UIAxes,-135,30)
                app.Light = camlight(app.UIAxes,'headlight');
            end
            material(app.UIAxes,'dull')
            lighting(app.UIAxes,'gouraud')

            %App Updates
            if ~app.STLMenu.Enable
                app.STLMenu.Enable = 'on';
            end
        end

        function generateSlicesFromVolumeFile(app,file)
            %Get filename
            [~,filename] = fileparts(file);

            %Read Volume Information
            SliceHdr = MRIread(file); %<-iELVis Function

            %Generate Structure Name
            subStructName = makeValidStructureNameFromFilename(app,filename,app.SlicesHandle);

            %Transform Volume to RAS orientation space 
            app.Slice.(subStructName).Torig = SliceHdr.tkrvox2ras;
            app.Slice.(subStructName).Image = SliceHdr.vol;
            Torig_affine = affine3d(app.Slice.(subStructName).Torig');
            app.Slice.(subStructName).ImageRAS = imwarp(app.Slice.(subStructName).Image,Torig_affine);
            app.Slice.(subStructName).ImageRASc = (app.Slice.(subStructName).ImageRAS-min(app.Slice.(subStructName).ImageRAS(:)))/(max(app.Slice.(subStructName).ImageRAS(:))-min(app.Slice.(subStructName).ImageRAS(:)))*256;
            
            %Generate AlphaMask
            if isempty(app.AlphaMask)
                app.AlphaMask = ones(size(app.Slice.(subStructName).ImageRAS));
            end
            
            %Modify Colorspace for Labeled Volumes
            if contains(filename,'aseg')
                labelSlice(app)
            end

            %Create inputs for slice function
            tmpX = linspace(-127,128,size(app.Slice.(subStructName).ImageRAS,1));
            tmpY = linspace(-127,128,size(app.Slice.(subStructName).ImageRAS,2));
            tmpZ = linspace(-127,128,size(app.Slice.(subStructName).ImageRAS,3));
            [X,Y,Z] = meshgrid(tmpY,tmpX,tmpZ);

            %Generate/Modify Figure/Object Handles
            h = slice(app.UIAxes,X,Y,Z,app.Slice.(subStructName).ImageRASc,0,0,0);
            sliceTags = {'Sagittal','Coronal','Transverse'};
            arg1 = {':',128,':';128,':',':';':',':',128};
            for i = 1:numel(h)
                app.SlicesHandle.(subStructName).(sliceTags{i}) = h(i);
                app.SlicesHandle.(subStructName).(sliceTags{i}).AlphaData = squeeze(app.AlphaMask(arg1{i,:}));
                app.SlicesHandle.(subStructName).(sliceTags{i}).AlphaDataMapping = 'none';
                app.SlicesHandle.(subStructName).(sliceTags{i}).FaceLighting = 'none';
                app.SlicesHandle.(subStructName).(sliceTags{i}).BackFaceLighting = 'unlit';
                app.SlicesHandle.(subStructName).(sliceTags{i}).FaceAlpha = 'flat'; %<-Set to 'flat' to use AlphaData
                app.SlicesHandle.(subStructName).(sliceTags{i}).EdgeColor = 'none';
                app.SlicesHandle.(subStructName).(sliceTags{i}).EdgeAlpha = 'flat';
                app.SlicesHandle.(subStructName).(sliceTags{i}).CDataMapping = 'direct';
                app.SlicesHandle.(subStructName).(sliceTags{i}).ButtonDownFcn = @app.clickSliceCallback;
                app.SlicesHandle.(subStructName).(sliceTags{i}).Tag = sliceTags{i};
                app.SlicesHandle.(subStructName).(sliceTags{i}).PickableParts = 'visible';
                app.SlicesHandle.(subStructName).(sliceTags{i}).HitTest = ~app.Rotate3DOn;
            end
            app.SliceNames = [app.SliceNames {subStructName}];
        end

        function labelSlice(app)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            tbl = readtable(fullfile(pathToMLAPP, 'LUT', 'FreesurferLUT.csv'));
            app.SliceAtlas = tbl;
            colorAtlas = [tbl.R,tbl.G,tbl.B]./255;
            colormap(app.UIAxes,colorAtlas)
            app.ColormapName = 'Custom';
            app.CustomColormap = colorAtlas;
        end

        function generateSurfacesFromVolumeFile(app,file)
            %Get Volume Information
            mri = MRIread(fullfile(file)); %<-iELVis Function
            Torig = mri.tkrvox2ras;
            mriVolume = mri.vol;

            if ~any(isgraphics(app.UIAxes.Children,'Light'))
                view(app.UIAxes,-135,30)
                app.Light = camlight(app.UIAxes,'headlight');
            end

            labels = unique(mriVolume);
            pathToMLAAP = fileparts(mfilename('fullpath'));
            LUT = readtable(fullfile(pathToMLAAP,'LUT','FreesurferLUT.csv'));
            [~,idxA] = intersect(LUT.Index,labels,'stable');
            LUT = LUT(idxA,:);
            [idx,tf] = listdlg('ListString',LUT.Label);
            if ~tf
                return
            end
            LUT = LUT(idx,:);

            d = uiprogressdlg(app.UIFigure,'Title','Generating Surfaces','Value',0);
            for i = 1:size(LUT,1)
                d.Value = i/size(LUT,1);
                volume = mriVolume==LUT.Index(i);
                subStructName = LUT.Label{i};
                if strcmp(subStructName,'Unknown')
                    continue
                end
                subStructName = matlab.lang.makeValidName(subStructName);
                subStructName = regexprep(subStructName,'_','');
                d.Message = subStructName;
                % [surf.faces,surf.verts] = extractIsosurface(volume,100);
                filledVolume = imfill(volume,'holes');
                surf = isosurface(filledVolume);
                surf.vertices = SurfaceSmooth(surf,[],1);
                surf.vertices = surf.vertices*Torig(1:3,1:3)';
                surf.vertices(:,1) = surf.vertices(:,1)+Torig(1,4);
                surf.vertices(:,2) = surf.vertices(:,2)+Torig(2,4);
                surf.vertices(:,3) = surf.vertices(:,3)+Torig(3,4);
                app.SurfacesHandle.(subStructName) = trisurf(surf.faces,surf.vertices(:,1),surf.vertices(:,2),surf.vertices(:,3),'Parent',app.UIAxes);
                app.SurfacesHandle.(subStructName).EdgeAlpha = 0;
                app.SurfacesHandle.(subStructName).PickableParts = 'visible';
                app.SurfacesHandle.(subStructName).HitTest = ~app.Rotate3DOn;
                app.SurfacesHandle.(subStructName).FaceColor = [LUT.R(i) LUT.G(i) LUT.B(i)]./256;
            end
            material(app.UIAxes,'dull')
            lighting(app.UIAxes,'gouraud')
            if ~app.STLMenu.Enable
                app.STLMenu.Enable = 'on';
            end
            close(d)
        end

        function loadNetwork(app,connectionTable)
            % Create Table to Determine Edges
            if isempty(connectionTable)
                path = uigetdir();
                files = dir(path);
                files([files.isdir]) = [];
                for i = 1:length(files)
                    fileFields = regexp(files(i).name,'Threshold_[\w-]+_(?<lead>[\w-]+)_(?<elec>\d+)\.mat$','names');
                    sourceNodeLabel = [fileFields.lead,sprintf('%02d',str2double(fileFields.elec))];
                    tmp = load(fullfile(files(i).folder,files(i).name));
                    chk = structfun(@istable,tmp);
                    fields = fieldnames(tmp);
                    analysisTable = tmp.(fields{find(chk)});
                    L = vartype('logical');
                    C = vartype('cell');
                    sinkNodeLabels = analysisTable{:,C}(analysisTable{:,L});
                    if isempty(sinkNodeLabels)
                        continue
                    end
                    drawEdgePlots(app,sourceNodeLabel,sinkNodeLabels)
                end
            else
                for i = 1:size(connectionTable,2)
                    tmp = connectionTable.Properties.VariableNames(i);
                    tableFields = regexp(tmp{:},'_(?<lead>[\w-]+)_(?<elec>\d+)$','names');
                    sourceNodeLabel = [tableFields.lead,sprintf('%02d',str2double(tableFields.elec))];
                    connectionLabels = connectionTable.Properties.RowNames;
                    sinkNodeLabels = connectionLabels(connectionTable{:,i});
                    if isempty(sinkNodeLabels)
                        continue
                    end
                    drawEdgePlots(app,sourceNodeLabel,sinkNodeLabels)
                end
            end
        end

        function drawEdgePlots(app,sourceNodeLabel,sinkNodeLabels)
            idx = ismember(app.Elecs.labels,sourceNodeLabel);
            if ~any(idx)
                return
            end
            startCoords = app.Elecs.coords(idx,:);
            idx = ismember(app.Elecs.labels,sinkNodeLabels);
            startCoords = repmat(startCoords,[sum(idx) 1]);
            endCoords = app.Elecs.coords(idx,:);
            edges = cat(3,startCoords,endCoords,nan(size(startCoords)));
            edges = permute(edges,[3 1 2]);
            x = reshape(edges(:,:,1),1,[]);
            y = reshape(edges(:,:,2),1,[]);
            z = reshape(edges(:,:,3),1,[]);
            app.NetworksHandle.(sourceNodeLabel) = plot3(app.UIAxes,x,y,z);
            gradientColorEdges(app,sourceNodeLabel)
        end

        function overlayNetwork(app)
            %Ignore Ref/Gnd
            bufferLabels = app.MainApp.BufferParameters.BufferLabels{1};
            bufferLabels = regexprep(bufferLabels,'^(C|Z)','');
            [matchingLabels,ia] = intersect(app.Elecs.labels,bufferLabels,'stable');

            %Create Graph and Plot
            app.Network = digraph(ones(length(ia)),matchingLabels,'omitselfloops');
            lineStyle = repmat({'none'},length(app.Network.Edges.Weight),1);
            tmp = app.Elecs.coords(ia,:);
            xCoord = tmp(:,1);
            yCoord = tmp(:,2);
            zCoord = tmp(:,3);
            app.NetworksHandle.DynamicNetwork = plot(app.UIAxes,app.Network,'XData',xCoord,'YData',yCoord,'ZData',zCoord,'HitTest','off');
            app.NetworksHandle.DynamicNetwork.NodeColor = repmat([0 0 1],length(matchingLabels),1);
            app.NetworksHandle.DynamicNetwork.MarkerSize = 10;
            app.NetworksHandle.DynamicNetwork.LineStyle = lineStyle;
            app.NetworksHandle.DynamicNetwork.ArrowPosition = 0.9;
        end

        function maskSlice(app,type,file)
            SliceHdr=MRIread(fullfile(app.fsfolder,file));
            mask=logical(SliceHdr.vol>0);
            tmp = app.Slice.(type).Image;
            tmp = tmp.*double(mask);
            app.Slice.(type).Image = zscore(tmp,[],'all');

            Torig_affine = affine3d(app.Slice.(type).Torig');
            app.Slice.(type).ImageRASMask = imwarp(app.Slice.(type).Image,Torig_affine);
        end

        function clickSliceCallback(app,src,event)
            sliceTag = src.Tag;
            switch sliceTag
                case 'Sagittal'
                    sliceID = 'X';
                    sliceIdx = 1;
                case 'Coronal'
                    sliceID = 'Y';
                    sliceIdx = 2;
                case 'Transverse'
                    sliceID = 'Z';
                    sliceIdx = 3;
            end
            oLoc = src.Parent.CurrentPoint(1,sliceIdx);
            iLoc = event.IntersectionPoint(sliceIdx);
            d = oLoc-iLoc;
            app.UIFigure.WindowButtonMotionFcn = @DragCallback;
            app.UIFigure.WindowButtonUpFcn = @app.doneCallback;
            function DragCallback(~,~)
                nL = src.Parent.CurrentPoint(1,sliceIdx);
                newInt = nL-d;
                idx = min(round(newInt),128);
                idx = max(idx,-127);
                arg = {':',idx+128,':';idx+128,':',':';':',':',idx+128};
                for k = 1:numel(app.SliceNames)
                    app.SlicesHandle.(app.SliceNames{k}).(sliceTag).([sliceID,'Data']) = ones(size(src.([sliceID,'Data'])))*idx;
                    app.SlicesHandle.(app.SliceNames{k}).(sliceTag).CData = squeeze(app.Slice.(app.SliceNames{k}).ImageRASc(arg{sliceIdx,:}));
                    app.SlicesHandle.(app.SliceNames{k}).(sliceTag).AlphaData = squeeze(app.AlphaMask(arg{sliceIdx,:}));
                end
            end
        end

        function convergeSlicesToPoint(app)
            a = app.UIAxes.CurrentPoint(1,:)-app.UIAxes.CurrentPoint(2,:);
            a = repmat(a,numel(app.UIFigure.CurrentObject.XData),1);
            pt = [app.UIFigure.CurrentObject.XData;app.UIFigure.CurrentObject.YData;app.UIFigure.CurrentObject.ZData]';
            b = pt - app.UIAxes.CurrentPoint(2,:);
            d = sqrt(sum(cross(a,b,2).^2,2))./sqrt(sum(a.^2,2));
            [~,ind] = min(d);
            sliceTag = {'Sagittal','Coronal','Transverse'};
            for i = 1:length(sliceTag)
                switch sliceTag{i}
                    case 'Sagittal'
                        sliceID = 'X';
                        sliceIdx = 1;
                    case 'Coronal'
                        sliceID = 'Y';
                        sliceIdx = 2;
                    case 'Transverse'
                        sliceID = 'Z';
                        sliceIdx = 3;
                end
                idx = round(app.UIFigure.CurrentObject.([sliceID,'Data'])(ind));
                arg = {':',idx+128,':';idx+128,':',':';':',':',idx+128};
                for k = 1:numel(app.SliceNames)
                    sz = size(app.SlicesHandle.(app.SliceNames{k}).(sliceTag{i}).([sliceID,'Data']));
                    app.SlicesHandle.(app.SliceNames{k}).(sliceTag{i}).([sliceID,'Data']) = ones(sz)*idx;
                    app.SlicesHandle.(app.SliceNames{k}).(sliceTag{i}).CData = squeeze(app.Slice.(app.SliceNames{k}).ImageRASc(arg{sliceIdx,:}));
                end
            end
        end

        function createLabelTooltip(app)
            
        end

        function selectionChangedCallback(app,~,~)
            app.Toolbar.Children(end).State = ~app.Toolbar.Children(end).State;
            toggleRotate(app)
        end

        function saveSnapshotCallback(app,~,~)
            formats = {'*.bmp','Bitmap (*.bmp)';...
                '*.gif','GIF (*.gif)';...
                '*.jpg;*.jpeg','JPEG (*.jpg)';...
                '*.png','PNG (*.png)';...
                '*.tif;*.tiff','TIFF (*.tiff)'};
            [file,path] = uiputfile(formats,'Save Image');
            if file==0
                return
            end
            if exist(fullfile(path,file),'file')
                ok = uiconfirm(app.UIFigure,'The image file already exists. Would you like to overwrite?','Overwrite?');
                if strcmp(ok,'No')
                    return
                end
            end
            F = getframe(app.UIFigure);
            [I,map] = frame2im(F);
            if ~isempty(map)
                imwrite(I,map,fullfile(path,file))
            else
                imwrite(I,fullfile(path,file))
            end
        end

        function toggleRotate(app)
            app.Toolbar.Children(end).State = ~app.Toolbar.Children(end).State;
            app.Rotate3DOn = ~app.Rotate3DOn;
            app.UIFigure.Pointer = 'watch';
            drawnow
            idx = find((isgraphics(app.UIAxes.Children,'surface') | isgraphics(app.UIAxes.Children,'patch'))==1);
            for i = 1:numel(idx)
                app.UIAxes.Children(idx(i)).HitTest = ~app.UIAxes.Children(idx(i)).HitTest;
            end
            app.UIFigure.Pointer = 'arrow';
        end

        function rotateAxesCallback(app,~,~)
            nloc = get(0,'PointerLocation');
            dx = nloc(1) - app.RotateParameters.Location(1);
            dy = nloc(2) - app.RotateParameters.Location(2);
            factor = pi;
            naz = mod(app.RotateParameters.Azimuth-dx/factor,360);
            nel = min(app.RotateParameters.Elevation-dy/factor,90);
            nel = max(nel,-90);
            view(app.UIAxes,naz,nel)
            if ~isempty(app.Light) && isvalid(app.Light)
                app.Light.Position = app.UIAxes.CameraPosition;
            end
        end

        function doneCallback(app,~,~)
            app.UIFigure.WindowButtonMotionFcn = [];
            app.UIFigure.WindowButtonUpFcn = [];
            if ~isempty(app.MovieApp) && isvalid(app.MovieApp)
                [app.RotateParameters.Azimuth, app.RotateParameters.Elevation] = view(app.UIAxes);
                app.RotateParameters.Location = get(0,'PointerLocation');
                app.MovieApp.updateFields()
            end
        end

        function name = makeValidStructureNameFromFilename(~,filename,handle)
            name = matlab.lang.makeValidName(filename);
            name = regexprep(name,'_','');
            if ~isempty(handle)
                if any(strcmp(name,fieldnames(handle)))
                    name = [name,'1'];
                end
            end
        end

    end

    methods (Access = public)
        function updateNetworkPlot(app)
        end

        function gradientColorEdges(app,sourceNodeLabel)
            startGrad = [255 0 0 1]';
            endGrad = [0 255 0 1]';
            gradient = uint8([startGrad,endGrad]);
            drawnow
            colorData = repmat(gradient,1,length(app.NetworksHandle.(sourceNodeLabel).XData)/3);
            set(app.NetworksHandle.(sourceNodeLabel).Edge,'ColorBinding','interpolated','ColorData',colorData)
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            
            pathToMLAPP = fileparts(mfilename('fullpath'));
            addpath(fullfile(pathToMLAPP))
            addpath(genpath(fullfile(pathToMLAPP,'utils')))


            %Setup UIAxes Properties
            axis(app.UIAxes,'vis3d')
            axis(app.UIAxes,'equal')
            axis(app.UIAxes,'off')
            app.UIAxes.Toolbar = [];
            axis(app.UIAxes2,'vis3d')
            axis(app.UIAxes2,'equal')
            axis(app.UIAxes2,'off')
            app.UIAxes2.Toolbar = [];
            app.AxesLink = linkprop([app.UIAxes,app.UIAxes2],{'View'});
            %Setup Toolbar
            app.Toolbar = uitoolbar(app.UIFigure);
            tt = uitoggletool(app.Toolbar);
            tt.Icon = fullfile(matlabroot,'toolbox','matlab','icons','tool_rotate_3d.png');
            tt.Tooltip = 'Rotate3D (Z)';
            tt.ClickedCallback = @app.selectionChangedCallback;
            pt = uipushtool(app.Toolbar);
            pt.Icon = fullfile(pathToMLAPP,'Icons','camera-button.png');
            pt.Tooltip = 'Save Snapshot (S)';
            pt.ClickedCallback = @app.saveSnapshotCallback;
            
            app.UIFigure.Visible = 'on';

            getIELVISpath(app)


            if nargin==2
                app.MainApp = callingApp;
                if isempty(app.MainApp.SubjectID)
                    selectTemplateImagingFolder(app)
                else
                     if ispc
                         app.baseFsFolderpath = fullfile('Z:','ECoG_Data');
                     else
                         app.baseFsFolderpath = fullfile(filesep,'Volumes','bcm-neurosurgery-ecog','ECoG_Data');
                     end
                    app.fsfolder = fullfile(app.baseFsFolderpath,[app.MainApp.SubjectID,'Datafile'],'IMG',app.MainApp.SubjectID);
                    createDatasetListOptions(app)
                end
            end
        end

        % Menu selected function: SliceMenu
        function LoadSliceMenuSelected(app, event)
            [file,path] = uigetfile({'*.mgh;*.mgz;*.bhdr;*.img;*.nii;*.nii.gz','iELVis Compatible Files (*.mgh,*.mgz,*.bhdr,*.img,*.nii,*.nii.gz)'},[],app.currentFolder);
            if file == 0
                return
            end
            app.currentFolder = path;
            file = fullfile(path,file);
            [~,filename] = fileparts(file);
            name = makeValidStructureNameFromFilename(app,filename,app.SlicesHandle);
            generateSlicesFromVolumeFile(app,name,fullfile(app.currentFolder,file))
        end

        % Menu selected function: LoadfromSurfaceFileMenu
        function LoadSurfaceMenuSelected(app, event)
            [filename,path] = uigetfile('*.*');
            if filename==0
                return
            end
            app.currentFolder = path;
            file = fullfile(path,filename);
            generateSurfaceFromSurfaceFile(app,file);
        end

        % Menu selected function: ImageSettingsMenu
        function ImageSettingsMenuSelected(app, event)
            app.SettingsApp = BrainViewer_ImageSettings(app);
        end

        % Menu selected function: AtlasMenu
        function AtlasMenuSelected(app, event)
            app.AtlasApp = BrainViewer_Atlas(app);
        end

        % Menu selected function: MovieMakerMenu
        function MovieMakerMenuSelected(app, event)
            app.MovieApp = BrainViewer_MovieMaker(app);
        end

        % Menu selected function: ClearViewerMenu
        function ClearMenuSelected(app, event)
            resetViewer(app)
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'z'
                    toggleRotate(app)
                case 's'
                    saveSnapshotCallback(app)
                case 'f12'
                    if any(contains(event.Modifier,'control'))
                        keyboard
                    end
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            switch app.UIFigure.SelectionType
                case 'normal' %Rotate Viewer
                    if app.Rotate3DOn
                        [app.RotateParameters.Azimuth, app.RotateParameters.Elevation] = view(app.UIAxes);
                        app.RotateParameters.Location = get(0,'PointerLocation');
                        app.UIFigure.WindowButtonMotionFcn = @app.rotateAxesCallback;
                        app.UIFigure.WindowButtonUpFcn = @app.doneCallback;
                    end
                case 'alt'
                    if ~isempty(app.SlicesHandle) && contains(fieldnames(app.SlicesHandle),'LabeledMRI')
                        createLabelTooltip(app)
                    end
                case 'open' %Converge Slices on Electrode
                    if ~isempty(app.UIFigure.CurrentObject) && isgraphics(app.UIFigure.CurrentObject,'scatter') && ~isempty(app.SlicesHandle)
                        convergeSlicesToPoint(app)
                    end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            currentDir = pwd;
            cd(pathToMLAPP);
            
            closeWindow(app.SettingsApp)
            closeWindow(app.AtlasApp)
            closeWindow(app.MovieApp)

            rmpath(genpath(app.iELVisToolboxPath))
            rmpath(fullfile(pathToMLAPP))
            rmpath(genpath(fullfile(pathToMLAPP,'utils')))

            delete(app) %This one must be 'delete' instead of 'close' to avoid creating an infinite loop

            cd(currentDir);

            function closeWindow(handle) % Calls an app's UIFigureCloseRequestFcn
                if ~isempty(handle) && isvalid(handle)
                    close(handle.UIFigure)
                end
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            app.UIAxes.Position([3,4]) = app.UIFigure.Position([3,4]);
        end

        % Menu selected function: NetworkMenu
        function LoadNetworkMenuSelected(app, event)
            loadNetwork(app)
        end

        % Menu selected function: PatientsMenu
        function LoadPatientsMenuSelected(app, event)
            selectImagingFolder(app)
        end

        % Menu selected function: StreamlinesMenu
        function LoadStreamlinesMenuSelected(app, event)
            
        end

        % Menu selected function: STLMenu
        function ExportSTLMenuSelected(app, event)
            list = fieldnames(app.SurfacesHandle);
            [idx,tf] = listdlg('ListString',list,'PromptString',{'Select surfaces to export'},'SelectionMode','multiple','OKString','Export');
            if ~tf
                return
            end
            [filename,path] = uiputfile({'.stl','STL File (*.stl)'});
            if isempty(filename)
                return
            end
            figure(app.UIFigure);
            d = uiprogressdlg(app.UIFigure,'Message','Aggregating Surfaces for Export','Indeterminate','on');
            
            vertices = [];
            faces = [];
            for i = 1:length(idx)
                tmp = size(vertices,1);
                vertices = [vertices; app.SurfacesHandle.(list{idx(i)}).Vertices];
                faces = [faces; app.SurfacesHandle.(list{idx(i)}).Faces+tmp];
            end
            d.Message = 'Exporting';
            T = triangulation(faces,vertices);
            stlwrite(T,fullfile(path,filename))
            close(d)
        end

        % Menu selected function: GeneratefromLabeledVolumeMenu
        function GeneratefromLabeledVolumeMenuSelected(app, event)
            [filename,path] = uigetfile({'*.mgh;*.mgz;*.bhdr;*.img;*.nii;*.nii.gz','iELVis Compatible Files (*.mgh,*.mgz,*.bhdr,*.img,*.nii,*.nii.gz)'},[],app.currentFolder);
            if filename == 0
                return
            end
            app.currentFolder = path;
            file = fullfile(path,filename);
            generateSurfacesFromVolumeFile(app,file)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'gray');
            app.UIFigure.Name = 'Brain Viewer';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'brain-24.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.PatientsMenu = uimenu(app.LoadMenu);
            app.PatientsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPatientsMenuSelected, true);
            app.PatientsMenu.Text = 'Patient(s)';
            app.SliceMenu = uimenu(app.LoadMenu);
            app.SliceMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSliceMenuSelected, true);
            app.SliceMenu.Text = 'Slice';
            app.SurfaceMenu = uimenu(app.LoadMenu);
            app.SurfaceMenu.Text = 'Surface';
            app.LoadfromSurfaceFileMenu = uimenu(app.SurfaceMenu);
            app.LoadfromSurfaceFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSurfaceMenuSelected, true);
            app.LoadfromSurfaceFileMenu.Text = 'Load from Surface File';
            app.GeneratefromLabeledVolumeMenu = uimenu(app.SurfaceMenu);
            app.GeneratefromLabeledVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneratefromLabeledVolumeMenuSelected, true);
            app.GeneratefromLabeledVolumeMenu.Text = 'Generate from Labeled Volume';
            app.NetworkMenu = uimenu(app.LoadMenu);
            app.NetworkMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNetworkMenuSelected, true);
            app.NetworkMenu.Text = 'Network';
            app.StreamlinesMenu = uimenu(app.LoadMenu);
            app.StreamlinesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadStreamlinesMenuSelected, true);
            app.StreamlinesMenu.Enable = 'off';
            app.StreamlinesMenu.Text = 'Streamlines';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.STLMenu = uimenu(app.ExportMenu);
            app.STLMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSTLMenuSelected, true);
            app.STLMenu.Enable = 'off';
            app.STLMenu.Text = 'STL';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.ImageSettingsMenu = uimenu(app.ViewMenu);
            app.ImageSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageSettingsMenuSelected, true);
            app.ImageSettingsMenu.Text = 'Image Settings';
            app.AtlasMenu = uimenu(app.ViewMenu);
            app.AtlasMenu.MenuSelectedFcn = createCallbackFcn(app, @AtlasMenuSelected, true);
            app.AtlasMenu.Enable = 'off';
            app.AtlasMenu.Text = 'Atlas';
            app.MovieMakerMenu = uimenu(app.ViewMenu);
            app.MovieMakerMenu.MenuSelectedFcn = createCallbackFcn(app, @MovieMakerMenuSelected, true);
            app.MovieMakerMenu.Text = 'Movie Maker';
            app.ClearViewerMenu = uimenu(app.ViewMenu);
            app.ClearViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearViewerMenu.Text = 'Clear Viewer';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            colormap(app.UIAxes, 'gray')
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XLim = [-1 1];
            app.UIAxes2.YLim = [-1 1];
            app.UIAxes2.ZLim = [-1 1];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            colormap(app.UIAxes2, 'gray')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end