classdef BrainViewer_Atlas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    
    properties (Access = private)
        CallingApp
        DynamicComponents
    end
    
    methods (Access = private)
        
        function ViewSurface(app,event)
            ind = event.Indices(1,1);
            coord = app.CallingApp.AtlasData.(event.Source.Parent.Title).SurfaceCenter-app.CallingApp.AtlasData.(event.Source.Parent.Title).AvgCoord{ind};
            naz = atand(coord(2)/coord(1))-90+180*(sign(coord(1))<0);
            nel = -1*atand(coord(3)/hypot(coord(1),coord(2)));
            view(app.CallingApp.UIAxes,naz,nel)
            app.CallingApp.Light.Position = app.CallingApp.UIAxes.CameraPosition;
        end

        function refreshTable(app,tab)
            app.DynamicComponents.(['UITable',int2str(i)]).Data = table(app.CallingApp.AtlasData.(tab).Labels,cell(numel(app.CallingApp.AtlasData.(tab).Labels),1));
            for j = 1:length(app.CallingApp.AtlasData.(tab).Labels)
                tmp = uistyle('BackgroundColor',app.CallingApp.AtlasData.(tab).RGB{j}/255);
                addStyle(app.DynamicComponents.(['UITable',int2str(i)]),tmp,'cell',[j,2]);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            if nargin==2
                app.CallingApp = callingApp;
                app.DynamicComponents.TabGroup = uitabgroup(app.UIFigure);
                app.DynamicComponents.TabGroup.Position = [15 14 260 434];
                fn = fieldnames(app.CallingApp.AtlasData);
                for i = 1:numel(fn)
                    app.DynamicComponents.(['Tab',int2str(i)]) = uitab(app.DynamicComponents.TabGroup);
                    app.DynamicComponents.(['Tab',int2str(i)]).Title = fn{i};
                    app.DynamicComponents.(['UITable',int2str(i)]) = uitable(app.DynamicComponents.(['Tab',int2str(i)]));
                    app.DynamicComponents.(['UITable',int2str(i)]).ColumnName = {'Label','Color'};
                    app.DynamicComponents.(['UITable',int2str(i)]).RowName = {};
                    app.DynamicComponents.(['UITable',int2str(i)]).Position = [0 0 260 410];
                    app.DynamicComponents.(['UITable',int2str(i)]).Data = table(app.CallingApp.AtlasData.(fn{i}).Labels,cell(numel(app.CallingApp.AtlasData.(fn{i}).Labels),1));
                    app.DynamicComponents.(['UITable',int2str(i)]).CellSelectionCallback = createCallbackFcn(app,@ViewSurface,true);
                    for j = 1:length(app.CallingApp.AtlasData.(fn{i}).Labels)
                        tmp = uistyle('BackgroundColor',app.CallingApp.AtlasData.(fn{i}).RGB{j}/255);
                        addStyle(app.DynamicComponents.(['UITable',int2str(i)]),tmp,'cell',[j,2]);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainViewer_Atlas(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end