classdef BrainViewer_ImageSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Panel            matlab.ui.container.Panel
        Tree             matlab.ui.container.Tree
        TreeContextMenu  matlab.ui.container.ContextMenu
        ShowMenu         matlab.ui.container.Menu
        HideMenu         matlab.ui.container.Menu
        OtherMenu        matlab.ui.container.Menu
        DeleteMenu       matlab.ui.container.Menu
    end


    properties (Access = public)
        CallingApp
    end

    properties (Access = private)
        CallingAppName
        DynamicNodes
        DynamicComponents
        CurrentPrimaryNode
        SelectedNodeName
        SliceOverlayMethod = 'Transparency';
        RightClickSelection
        ShowStyle
        HideStyle
    end

    methods (Access = private)

        function createElectrodesSettingsComponents(app)
            % Create SetColorButton
            app.DynamicComponents.SetColorButton = uibutton(app.Panel, 'push');
            app.DynamicComponents.SetColorButton.ButtonPushedFcn = createCallbackFcn(app, @SetColorButtonPushed, true);
            app.DynamicComponents.SetColorButton.Position = [14 382 100 22];
            app.DynamicComponents.SetColorButton.Text = 'Set Color';

            % Create ColorBox
            app.DynamicComponents.ColorBox = uilabel(app.Panel);
            if strcmp(app.CurrentPrimaryNode,app.SelectedNodeName)
                fn1 = fieldnames(app.CallingApp.([app.CurrentPrimaryNode,'Handle']));
                app.DynamicComponents.ColorBox.BackgroundColor = app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(fn1{1}).Scatter.CData;
            else
                app.DynamicComponents.ColorBox.BackgroundColor = app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).Scatter.CData;
            end
            app.DynamicComponents.ColorBox.HorizontalAlignment = 'center';
            app.DynamicComponents.ColorBox.Position = [128 382 22 22];
            app.DynamicComponents.ColorBox.Text = '';

            % Create MarkerSizeEditFieldLabel
            app.DynamicComponents.MarkerSizeEditFieldLabel = uilabel(app.Panel);
            app.DynamicComponents.MarkerSizeEditFieldLabel.HorizontalAlignment = 'center';
            app.DynamicComponents.MarkerSizeEditFieldLabel.Position = [31 340 69 22];
            app.DynamicComponents.MarkerSizeEditFieldLabel.Text = 'Marker Size';

            % Create MarkerSizeEditField
            app.DynamicComponents.MarkerSizeEditField = uieditfield(app.Panel, 'numeric');
            app.DynamicComponents.MarkerSizeEditField.LowerLimitInclusive = 'off';
            app.DynamicComponents.MarkerSizeEditField.Limits = [0 Inf];
            app.DynamicComponents.MarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeEditFieldValueChanged, true);
            app.DynamicComponents.MarkerSizeEditField.HorizontalAlignment = 'center';
            app.DynamicComponents.MarkerSizeEditField.Position = [15 319 100 22];
            if strcmp(app.CurrentPrimaryNode,app.SelectedNodeName)
                app.DynamicComponents.MarkerSizeEditField.Value = 100;
            else
                app.DynamicComponents.MarkerSizeEditField.Value = app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).Scatter.SizeData;
            end

            % Create LineWidthEditFieldLabel
            app.DynamicComponents.LineWidthEditFieldLabel = uilabel(app.Panel);
            app.DynamicComponents.LineWidthEditFieldLabel.HorizontalAlignment = 'center';
            app.DynamicComponents.LineWidthEditFieldLabel.Position = [37 277 63 22];
            app.DynamicComponents.LineWidthEditFieldLabel.Text = 'Line Width';

            % Create LineWidthEditField
            app.DynamicComponents.LineWidthEditField = uieditfield(app.Panel, 'numeric');
            app.DynamicComponents.LineWidthEditField.LowerLimitInclusive = 'off';
            app.DynamicComponents.LineWidthEditField.Limits = [0 Inf];
            app.DynamicComponents.LineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @LineWidthEditFieldValueChanged, true);
            app.DynamicComponents.LineWidthEditField.HorizontalAlignment = 'center';
            app.DynamicComponents.LineWidthEditField.Position = [17 256 100 22];
            if strcmp(app.CurrentPrimaryNode,app.SelectedNodeName)
                app.DynamicComponents.LineWidthEditField.Value = 3;
            else
                app.DynamicComponents.LineWidthEditField.Value = app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).Line.LineWidth;
            end

            function SetColorButtonPushed(app,~)
                c = uisetcolor;
                if c==0
                    return
                end
                if strcmp(app.CurrentPrimaryNode,app.SelectedNodeName)
                    fn = fieldnames(app.CallingApp.([app.CurrentPrimaryNode,'Handle']));
                    for i = 1:length(fn)
                        app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(fn{i}).Scatter.CData = c;
                        app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(fn{i}).Line.Color = c;
                        app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(fn{i}).Text.Color = c;
                    end
                else
                    app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).Scatter.CData = c;
                    app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).Line.Color = c;
                    app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).Text.Color = c;
                end
                app.DynamicComponents.ColorBox.BackgroundColor = c;
            end

            function MarkerSizeEditFieldValueChanged(app,event)
                if strcmp(app.CurrentPrimaryNode,app.SelectedNodeName)
                    fn = fieldnames(app.CallingApp.([app.CurrentPrimaryNode,'Handle']));
                    for i = 1:length(fn)
                        app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(fn{i}).Scatter.SizeData = event.Value;
                    end
                else
                    app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).Scatter.SizeData = event.Value;
                end
            end

            function LineWidthEditFieldValueChanged(app,event)
                if strcmp(app.CurrentPrimaryNode,app.SelectedNodeName)
                    fn = fieldnames(app.CallingApp.([app.CurrentPrimaryNode,'Handle']));
                    for i = 1:length(fn)
                        app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(fn{i}).Line.LineWidth = event.Value;
                    end
                else
                    app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).Line.LineWidth = event.Value;
                end
            end

        end

        function createSurfacesSettingsComponents(app)
            % Create SetColorButton
            app.DynamicComponents.SetColorButton = uibutton(app.Panel, 'push');
            app.DynamicComponents.SetColorButton.ButtonPushedFcn = createCallbackFcn(app, @SetColorButtonPushed, true);
            app.DynamicComponents.SetColorButton.Position = [14 382 100 22];
            app.DynamicComponents.SetColorButton.Text = 'Set Color';

            % Create ColorBox
            app.DynamicComponents.ColorBox = uilabel(app.Panel);
            if strcmp(app.CurrentPrimaryNode,app.SelectedNodeName)
                fn1 = fieldnames(app.CallingApp.([app.CurrentPrimaryNode,'Handle']));
                if ischar(app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(fn1{1}).FaceColor)
                    app.DynamicComponents.ColorBox.BackgroundColor = 'none';
                else
                    app.DynamicComponents.ColorBox.BackgroundColor = app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(fn1{1}).FaceColor;
                end
            else
                if ischar(app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).FaceColor)
                    app.DynamicComponents.ColorBox.BackgroundColor = 'none';
                else
                    app.DynamicComponents.ColorBox.BackgroundColor = app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).FaceColor;
                end
            end
            app.DynamicComponents.ColorBox.HorizontalAlignment = 'center';
            app.DynamicComponents.ColorBox.Position = [128 382 22 22];
            app.DynamicComponents.ColorBox.Text = '';

            % Create SurfaceOpacitySliderLabel
            app.DynamicComponents.SurfaceOpacitySliderLabel = uilabel(app.Panel);
            app.DynamicComponents.SurfaceOpacitySliderLabel.HorizontalAlignment = 'center';
            app.DynamicComponents.SurfaceOpacitySliderLabel.Position = [237 387 92 22];
            app.DynamicComponents.SurfaceOpacitySliderLabel.Text = 'Surface Opacity';

            % Create SurfaceOpacitySlider
            app.DynamicComponents.SurfaceOpacitySlider = uislider(app.Panel);
            app.DynamicComponents.SurfaceOpacitySlider.Limits = [0 1];
            app.DynamicComponents.SurfaceOpacitySlider.Position = [207 375 150 3];
            if strcmp(app.CurrentPrimaryNode,app.SelectedNodeName)
                fn1 = fieldnames(app.CallingApp.([app.CurrentPrimaryNode,'Handle']));
                app.DynamicComponents.SurfaceOpacitySlider.Value = app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(fn1{1}).FaceAlpha;
                app.DynamicComponents.SurfaceOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @SurfaceOpacitySliderValueChanged, true);
            else
                app.DynamicComponents.SurfaceOpacitySlider.Value = app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).FaceAlpha;
                app.DynamicComponents.SurfaceOpacitySlider.ValueChangingFcn = createCallbackFcn(app, @SurfaceOpacitySliderValueChanging, true);
            end

            % Create ApplyAtlasButton
            app.DynamicComponents.ApplyAtlasButton = uibutton(app.Panel, 'push');
            app.DynamicComponents.ApplyAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAtlasButtonPushed, true);
            app.DynamicComponents.ApplyAtlasButton.Position = [15 340 100 22];
            app.DynamicComponents.ApplyAtlasButton.Text = 'Apply Atlas';

            function SetColorButtonPushed(app,~)
                c = uisetcolor;
                if c==0
                    return
                end
                if strcmp(app.CurrentPrimaryNode,app.SelectedNodeName)
                    fn = fieldnames(app.CallingApp.([app.CurrentPrimaryNode,'Handle']));
                    for i = 1:length(fn)
                        app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(fn{i}).FaceColor = c;
                    end
                else
                    app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).FaceColor = c;
                end
                app.DynamicComponents.ColorBox.BackgroundColor = c;
            end

            function SurfaceOpacitySliderValueChanged(app,~)
                value = app.DynamicComponents.SurfaceOpacitySlider.Value;
                if strcmp(app.CurrentPrimaryNode,app.SelectedNodeName)
                    fn = fieldnames(app.CallingApp.([app.CurrentPrimaryNode,'Handle']));
                    for i = 1:length(fn)
                        app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(fn{i}).FaceAlpha = value;
                    end
                else
                    app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).FaceAlpha = value;
                end
            end

            function SurfaceOpacitySliderValueChanging(app,event)
                if strcmp(app.CurrentPrimaryNode,app.SelectedNodeName)
                    fn = fieldnames(app.CallingApp.([app.CurrentPrimaryNode,'Handle']));
                    for i = 1:length(fn)
                        app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(fn{i}).FaceAlpha = event.Value;
                    end
                else
                    app.CallingApp.([app.CurrentPrimaryNode,'Handle']).(app.SelectedNodeName).FaceAlpha = event.Value;
                end
            end

            function ApplyAtlasButtonPushed(app,~)
                [file,path] = uigetfile(fullfile(app.CallingApp.fsfolder,'label','*.annot'));
                if file == 0
                    return
                end
                if strcmp(app.CurrentPrimaryNode,app.SelectedNodeName)
                    d = dir(path);
                    genFile = regexprep(file,'(l|r)h','');
                    ind = contains({d.name},genFile);
                    files = {d(ind).name};
                else
                    files = {file};
                end
                for i = 1:numel(files)
                    hem = regexp(files{i},'(l|r)h','match');
                    hem = hem{1};
                    [~,label,colorTable] = read_annotation(fullfile(path,files{i}));
                    app.CallingApp.AtlasData.([hem,'pial']) = [];
                    app.CallingApp.AtlasData.([hem,'pial']).Labels = colorTable.struct_names;
                    app.CallingApp.AtlasData.([hem,'pial']).RGB = cell(numel(colorTable.struct_names),1);
                    app.CallingApp.AtlasData.([hem,'pial']).Idx = cell(numel(colorTable.struct_names),1);
                    app.CallingApp.AtlasData.([hem,'pial']).AvgCoord = cell(numel(colorTable.struct_names),1);
                    app.CallingApp.AtlasData.([hem,'pial']).SurfaceCenter = mean(app.CallingApp.([app.CurrentPrimaryNode,'Handle']).([hem,'pial']).Vertices,1);
                    colorMapping = zeros(numel(label),3);
                    for j = 1:numel(colorTable.struct_names)
                        vert_indx = label==colorTable.table(j,5);
                        rgb = colorTable.table(j,1:3);
                        app.CallingApp.AtlasData.([hem,'pial']).RGB(j) = {rgb};
                        app.CallingApp.AtlasData.([hem,'pial']).Idx(j) = {find(vert_indx==1)};
                        app.CallingApp.AtlasData.([hem,'pial']).AvgCoord{j} = mean(app.CallingApp.([app.CurrentPrimaryNode,'Handle']).([hem,'pial']).Vertices(vert_indx,:),1);
                        colorMapping(vert_indx,:) = repmat(rgb./255,sum(vert_indx),1);
                    end
                    app.CallingApp.([app.CurrentPrimaryNode,'Handle']).([hem,'pial']).FaceVertexCData = colorMapping;
                    app.CallingApp.([app.CurrentPrimaryNode,'Handle']).([hem,'pial']).FaceColor = 'interp';
                end
                app.DynamicComponents.ColorBox.BackgroundColor = 'none';
                app.CallingApp.AtlasMenu.Enable = 'on';
            end

        end

        function createSlicesSettingsComponents(app)
            % Create UIAxes
            app.DynamicComponents.UIAxes = uiaxes(app.Panel);
            app.DynamicComponents.UIAxes.XTick = [];
            app.DynamicComponents.UIAxes.YTick = [];
            app.DynamicComponents.UIAxes.XLim = [0,257];
            app.DynamicComponents.UIAxes.YLim = [0.5,1.5];
            app.DynamicComponents.UIAxes.TickDir = 'none';
            app.DynamicComponents.UIAxes.Box = 'on';
            app.DynamicComponents.UIAxes.Position = [196 388 210 22];
            app.DynamicComponents.UIAxes.Toolbar = [];
            app.DynamicComponents.UIAxes.Colormap = app.CallingApp.UIAxes.Colormap;
            imagesc(app.DynamicComponents.UIAxes,'XData',1:256,'YData',1,'CData',1:256)

            % Create ColormapDropDownLabel
            app.DynamicComponents.ColormapDropDownLabel = uilabel(app.Panel);
            app.DynamicComponents.ColormapDropDownLabel.HorizontalAlignment = 'right';
            app.DynamicComponents.ColormapDropDownLabel.Position = [14 388 58 22];
            app.DynamicComponents.ColormapDropDownLabel.Text = 'Colormap';

            % Create ColormapDropDown
            app.DynamicComponents.ColormapDropDown = uidropdown(app.Panel);
            app.DynamicComponents.ColormapDropDown.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet', 'lines', 'colorcube', 'prism', 'flag', 'white', 'Custom'};
            app.DynamicComponents.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.DynamicComponents.ColormapDropDown.Position = [87 388 100 22];
            app.DynamicComponents.ColormapDropDown.Value = app.CallingApp.ColormapName;

            % Create ApplyMaskButton
            app.DynamicComponents.ApplyMaskButton = uibutton(app.Panel, 'push');
            app.DynamicComponents.ApplyMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyMaskButtonPushed, true);
            app.DynamicComponents.ApplyMaskButton.Position = [15 292 100 22];
            app.DynamicComponents.ApplyMaskButton.Text = 'Apply Mask';

            %             % Create EditField
            %             app.DynamicComponents.EditField = uieditfield(app.Panel, 'text');
            %             app.DynamicComponents.EditField.Editable = 'off';
            %             app.DynamicComponents.EditField.Position = [125 292 281 22];

            % Create SliceOverlayDropDownLabel
            app.DynamicComponents.SliceOverlayDropDownLabel = uilabel(app.Panel);
            app.DynamicComponents.SliceOverlayDropDownLabel.HorizontalAlignment = 'right';
            app.DynamicComponents.SliceOverlayDropDownLabel.Position = [15 204 75 22];
            app.DynamicComponents.SliceOverlayDropDownLabel.Text = 'Slice Overlay';

            % Create SliceOverlayDropDown
            app.DynamicComponents.SliceOverlayDropDown = uidropdown(app.Panel);
            app.DynamicComponents.SliceOverlayDropDown.Items = {'Transparency', 'Checkered Tiling', 'Horizontal Tiling', 'Vertical Tiling'};
            app.DynamicComponents.SliceOverlayDropDown.ValueChangedFcn = createCallbackFcn(app, @SliceOverlayDropDownValueChanged, true);
            app.DynamicComponents.SliceOverlayDropDown.Position = [105 204 117 22];
            app.DynamicComponents.SliceOverlayDropDown.Value = 'Transparency';

            % Create Subcomponents
            createSliceSettingsSubcomponents(app,app.SliceOverlayMethod)

            function ColormapDropDownValueChanged(app,event)
                if ~strcmp(event.Value,'Custom')
                    colorFunc = str2func(event.Value);
                    tmp = colorFunc();
                else
                    tmp = app.CallingApp.CustomColormap;
                end
                colormap(app.DynamicComponents.UIAxes,tmp)
                app.CallingApp.UIAxes.Colormap = tmp;
                app.CallingApp.ColormapName = event.Value;
            end

            function ApplyMaskButtonPushed(app,~)
                [file,path] = uigetfile(fullfile(app.CallingApp.fsfolder,'*.*'));
                SliceHdr = MRIread(fullfile(path,file));
                mask = double(SliceHdr.vol>0);
                Torig_affine = affine3d(app.CallingApp.Slice.MRI.Torig');
                tmp = imwarp(mask,Torig_affine);
                app.CallingApp.AlphaMask = double(tmp>0);
                objList = unique(findobj(app.Tree.SelectedNodes));
                indC = cellfun(@isempty,{objList.Children});
                objList = objList(indC);
                for i = 1:numel(objList)
                    fieldPath = split(objList(i).Tag,'_');
                    tmp1 = getfield(app.CallingApp,fieldPath{:});
                    switch fieldPath{3}
                        case 'Sagittal'
                            sliceID = 'X';
                            sliceIdx = 1;
                        case 'Coronal'
                            sliceID = 'Y';
                            sliceIdx = 2;
                        case 'Transverse'
                            sliceID = 'Z';
                            sliceIdx = 3;
                    end
                    idx = tmp1.([sliceID,'Data'])(1,1);
                    arg = {':',idx+128,':';idx+128,':',':';':',':',idx+128};
                    tmp1.AlphaData = squeeze(app.CallingApp.AlphaMask(arg{sliceIdx,:}));
                end
            end

            function SliceOverlayDropDownValueChanged(app,~)
                app.SliceOverlayMethod = app.DynamicComponents.SliceOverlayDropDown.Value;
                createSliceSettingsSubcomponents(app,app.SliceOverlayMethod)
            end

        end

        function createSliceSettingsSubcomponents(app,selectedOption)

            if any(strcmp(fieldnames(app.DynamicComponents),'Level1'))
                deleteDynamicComponents(app,'Level1')
            end

            switch selectedOption
                case 'Transparency'
                    % Create OpacitySliderLabel
                    app.DynamicComponents.Level1.OpacitySliderLabel = uilabel(app.Panel);
                    app.DynamicComponents.Level1.OpacitySliderLabel.HorizontalAlignment = 'center';
                    app.DynamicComponents.Level1.OpacitySliderLabel.Position = [190 146 47 22];
                    app.DynamicComponents.Level1.OpacitySliderLabel.Text = 'Opacity';

                    % Create OpacitySlider
                    app.DynamicComponents.Level1.OpacitySlider = uislider(app.Panel);
                    app.DynamicComponents.Level1.OpacitySlider.Limits = [0 1];
                    app.DynamicComponents.Level1.OpacitySlider.ValueChangingFcn = createCallbackFcn(app, @OpacitySliderValueChanging, true);
                    app.DynamicComponents.Level1.OpacitySlider.Position = [137 134 150 3];
                    app.DynamicComponents.Level1.OpacitySlider.Value = 1; %<-Need to generalize to the state of the selected object

                case 'Checkered Tiling'

                case 'Horizontal Tiling'

                case 'Vertical Tiling'

            end

            function OpacitySliderValueChanging(app,event)
                objList = unique(findobj(app.Tree.SelectedNodes));
                indC = cellfun(@isempty,{objList.Children});
                objList = objList(indC);
                for i = 1:numel(objList)
                    fieldPath = split(objList(i).Tag,'_');
                    tmp1 = getfield(app.CallingApp,fieldPath{:});
                    %                     switch fieldPath{3}
                    %                         case 'Sagittal'
                    %                             sliceID = 'X';
                    %                             sliceIdx = 1;
                    %                         case 'Coronal'
                    %                             sliceID = 'Y';
                    %                             sliceIdx = 2;
                    %                         case 'Transverse'
                    %                             sliceID = 'Z';
                    %                             sliceIdx = 3;
                    %                     end
                    %                     idx = tmp1.([sliceID,'Data'])(1,1);
                    %                     arg = {':',idx+128,':';idx+128,':',':';':',':',idx+128};
                    %                     tmp1.AlphaData = squeeze(app.CallingApp.AlphaMask(arg{sliceIdx,:})).*event.Value;
                    tmp1.FaceAlpha = event.Value;
                end
            end


        end

        function deleteDynamicComponents(app,comp)
            if nargin<2
                comp = [];
            end
            if isempty(comp)
                fields = fieldnames(app.DynamicComponents);
                for i = 1:numel(fields)
                    delete(app.DynamicComponents.(fields{i}))
                    app.DynamicComponents = rmfield(app.DynamicComponents,fields{i});
                end
            else
                fields = fieldnames(app.DynamicComponents.(comp));
                for i = 1:numel(fields)
                    delete(app.DynamicComponents.(comp).(fields{i}))
                    app.DynamicComponents.(comp) = rmfield(app.DynamicComponents.(comp),fields{i});
                end
                app.DynamicComponents = rmfield(app.DynamicComponents,comp);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            if nargin==2
                app.CallingApp = callingApp;
                fn = fieldnames(app.CallingApp);
                ind1 = contains(fn,'Handle');
                ind2 = false(size(ind1));
                for i = 1:numel(ind1)
                    ind2(i) = ~isempty(app.CallingApp.(fn{i}));
                end
                h = fn(ind1 & ind2);
                for i = 1:length(h)
                    nodeName = regexprep(h{i},'Handle','');
                    app.DynamicNodes.(nodeName) = uitreenode(app.Tree,'Text',nodeName,'Tag',[nodeName,'Handle']);
                    app.DynamicNodes.(nodeName).ContextMenu = app.TreeContextMenu;
                    switch nodeName
                        case {'Electrodes','Surfaces','Networks'}
                            fn1 = fieldnames(app.CallingApp.(h{i}));
                            for j = 1:numel(fn1)
                                app.DynamicNodes.([nodeName,'_',fn1{j}]) = uitreenode(app.DynamicNodes.(nodeName),'Text',fn1{j},'Tag',[nodeName,'Handle_',fn1{j}]);
                                app.DynamicNodes.([nodeName,'_',fn1{j}]).ContextMenu = app.TreeContextMenu;
                            end
                        case 'Slices'
                            fn1 = fieldnames(app.CallingApp.(h{i}));
                            for j = 1:numel(fn1)
                                app.DynamicNodes.([nodeName,'_',fn1{j}]) = uitreenode(app.DynamicNodes.(nodeName),'Text',fn1{j},'Tag',[nodeName,'Handle_',fn1{j}]);
                                app.DynamicNodes.([nodeName,'_',fn1{j}]).ContextMenu = app.TreeContextMenu;
                                fn11 = fieldnames(app.CallingApp.(h{i}).(fn1{j}));
                                for k = 1:numel(fn11)
                                    app.DynamicNodes.([nodeName,'_',fn1{j},'_',fn11{k}]) = uitreenode(app.DynamicNodes.([nodeName,'_',fn1{j}]),'Text',fn11{k},'Tag',[nodeName,'Handle_',fn1{j},'_',fn11{k}]);
                                    app.DynamicNodes.([nodeName,'_',fn1{j},'_',fn11{k}]).ContextMenu = app.TreeContextMenu;
                                end
                            end
                    end
                end
            end
            app.ShowStyle = uistyle('FontColor',[0 0 0]);
            app.HideStyle = uistyle('FontColor',[0.5 0.5 0.5]);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            if strcmp(app.UIFigure.SelectionType,'alt')
                app.SelectedNodeName = selectedNode.Text;
                return
            end
            selectedNode = app.Tree.SelectedNodes(1);
            app.SelectedNodeName = selectedNode.Text;
            node = selectedNode;
            while isgraphics(node.Parent,'uitreenode')
                node = node.Parent;
            end
            if strcmp(app.CurrentPrimaryNode,node)
                return
            end
            if ~isempty(app.DynamicComponents)
                if any(strcmp(fieldnames(app.DynamicComponents),'Level1'))
                    deleteDynamicComponents(app,'Level1')
                end
                deleteDynamicComponents(app);
            end
            app.CurrentPrimaryNode = node.Text;
            switch node.Text
                case 'Electrodes'
                    createElectrodesSettingsComponents(app)
                case 'Surfaces'
                    createSurfacesSettingsComponents(app)
                case 'Slices'
                    createSlicesSettingsComponents(app)
            end
        end

        % Menu selected function: ShowMenu
        function ShowMenuSelected(app, event)
            app.UIFigure.Pointer = 'watch';
            app.CallingApp.UIFigure.Pointer = 'watch';
            drawnow
            if ismember(app.UIFigure.CurrentObject,app.Tree.SelectedNodes)
                styleList = unique(findobj(app.Tree.SelectedNodes));
            else
                styleList = unique(findobj(app.UIFigure.CurrentObject));
            end
            indC = cellfun(@isempty,{styleList.Children});
            childrenList = styleList(indC);
            addStyle(app.Tree,app.ShowStyle,'node',styleList)
            for i = 1:numel(childrenList)
                fieldPath = split(childrenList(i).Tag,'_');
                switch fieldPath{1}
                    case 'ElectrodesHandle'
                        tmp = getfield(app.CallingApp,fieldPath{:},'Scatter');
                        tmp.Visible = 1;
                        tmp = getfield(app.CallingApp,fieldPath{:},'Line');
                        tmp.Visible = 1;
                        tmp = getfield(app.CallingApp,fieldPath{:},'Text');
                        tmp.Visible = 1;
                    case 'NetworksHandle'
                        tmp = getfield(app.CallingApp,fieldPath{:});
                        tmp.Visible = 1;
                        app.CallingApp.gradientColorEdges(fieldPath{2})
                    otherwise
                        tmp = getfield(app.CallingApp,fieldPath{:});
                        tmp.Visible = 1;
                end
            end
            app.UIFigure.Pointer = 'arrow';
            app.CallingApp.UIFigure.Pointer = 'arrow';
        end

        % Menu selected function: HideMenu
        function HideMenuSelected(app, event)
            app.UIFigure.Pointer = 'watch';
            app.CallingApp.UIFigure.Pointer = 'watch';
            drawnow
            if ismember(app.UIFigure.CurrentObject,app.Tree.SelectedNodes)
                styleList = unique(findobj(app.Tree.SelectedNodes));
            else
                styleList = unique(findobj(app.UIFigure.CurrentObject));
            end
            indC = cellfun(@isempty,{styleList.Children});
            childrenList = styleList(indC);
            addStyle(app.Tree,app.HideStyle,'node',styleList)
            for i = 1:numel(childrenList)
                fieldPath = split(childrenList(i).Tag,'_');
                switch fieldPath{1}
                    case 'ElectrodesHandle'
                        tmp = getfield(app.CallingApp,fieldPath{:},'Scatter');
                        tmp.Visible = 0;
                        tmp = getfield(app.CallingApp,fieldPath{:},'Line');
                        tmp.Visible = 0;
                        tmp = getfield(app.CallingApp,fieldPath{:},'Text');
                        tmp.Visible = 0;
                    case 'NetworksHandle'
                        tmp = getfield(app.CallingApp,fieldPath{:});
                        tmp.Visible = 0;
                    otherwise
                        tmp = getfield(app.CallingApp,fieldPath{:});
                        tmp.Visible = 0;
                end
            end
            app.UIFigure.Pointer = 'arrow';
            app.CallingApp.UIFigure.Pointer = 'arrow';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.SettingsApp = [];
            delete(app)
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            app.UIFigure.Pointer = 'watch';
            app.CallingApp.UIFigure.Pointer = 'watch';
            drawnow
            if ismember(app.UIFigure.CurrentObject,app.Tree.SelectedNodes)
                deleteList = unique(findobj(app.Tree.SelectedNodes));
            else
                deleteList = unique(findobj(app.UIFigure.CurrentObject));
            end
            %             fieldPath = cellfun(@(x) split(x,'_'),{deleteList.Tag},'Uni',0);
            %             pathDepth = cellfun(@length,fieldPath);
            %             [~,sortedDeleteListIdx] = sort(pathDepth,'descend');
            %             deleteList = deleteList(sortedDeleteListIdx);
            %             for i = 1:numel(deleteList)
            %                 fieldPath = split(deleteList(1).Tag,'_');
            %                 tmpHandle = getfield(app.CallingApp,fieldPath{:});
            %                 if isgraphics(tmpHandle)
            %                     delete(tmpHandle)
            %                 end
            %                 delete(deleteList(1))
            %                 deleteList(1) = [];
            %             end
            %             % The deleting algorithm should be redesigned such that a tree
            %             % of selected objects where the graphics objects are the leaves
            %             % should be created and converted into a sorted list
            indC = cellfun(@isempty,{deleteList.Children});
            childrenList = deleteList(indC);
            for i = 1:numel(childrenList)
                fieldPath = split(childrenList(i).Tag,'_');
                tmpHandle = getfield(app.CallingApp,fieldPath{:});
                if strcmp(fieldPath{1},'ElectrodesHandle')
                    tmp = getfield(app.CallingApp,fieldPath{:},'Scatter');
                    delete(tmp);
                    tmp = getfield(app.CallingApp,fieldPath{:},'Line');
                    delete(tmp);
                    tmp = getfield(app.CallingApp,fieldPath{:},'Text');
                    delete(tmp);
                else
                    delete(tmpHandle);
                end
                app.CallingApp.(fieldPath{1}) = rmfield(app.CallingApp.(fieldPath{1}),fieldPath{2});
            end
            for i = numel(deleteList):-1:1
                delete(deleteList(i))
            end
            app.UIFigure.Pointer = 'arrow';
            app.CallingApp.UIFigure.Pointer = 'arrow';
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'f12'
                    if any(contains(event.Modifier,'control'))
                        keyboard
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Settings';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.Tree = uitree(app.UIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.TreeContextMenu = uicontextmenu(app.UIFigure);
            app.ShowMenu = uimenu(app.TreeContextMenu);
            app.ShowMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowMenuSelected, true);
            app.ShowMenu.Text = 'Show';
            app.HideMenu = uimenu(app.TreeContextMenu);
            app.HideMenu.MenuSelectedFcn = createCallbackFcn(app, @HideMenuSelected, true);
            app.HideMenu.Text = 'Hide';
            app.OtherMenu = uimenu(app.TreeContextMenu);
            app.OtherMenu.Text = 'Other';
            app.DeleteMenu = uimenu(app.OtherMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainViewer_ImageSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end