classdef BrainViewer_MovieMaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ShowOrientationAxesCheckBox  matlab.ui.control.CheckBox
        PreviewMovieButton           matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        RotateTab                    matlab.ui.container.Tab
        DegreesEditField             matlab.ui.control.NumericEditField
        DegreesEditFieldLabel        matlab.ui.control.Label
        DirectionDropDown            matlab.ui.control.DropDown
        DirectionDropDownLabel       matlab.ui.control.Label
        CustomPathTab                matlab.ui.container.Tab
        ReturntoStartCheckBox        matlab.ui.control.CheckBox
        SamplesEditField             matlab.ui.control.NumericEditField
        SamplesEditFieldLabel        matlab.ui.control.Label
        PathStyleDropDown            matlab.ui.control.DropDown
        PathStyleDropDownLabel       matlab.ui.control.Label
        DeleteRowButton              matlab.ui.control.Button
        AddRowButton                 matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        GenerateMovieButton          matlab.ui.control.Button
        ElevationEditField           matlab.ui.control.NumericEditField
        ElevationEditFieldLabel      matlab.ui.control.Label
        AzimuthEditField             matlab.ui.control.NumericEditField
        AzimuthEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        MainApp
    end
    
    properties (Access = private)
        Property2 % Description
    end
    
    methods (Access = private)
        
        function rotateAxes(app)
            app.MainApp.RotateParameters.Azimuth = app.AzimuthEditField.Value;
            app.MainApp.RotateParameters.Elevation = app.ElevationEditField.Value;
            view(app.MainApp.UIAxes,app.AzimuthEditField.Value,app.ElevationEditField.Value)
            app.MainApp.Light.Position = app.MainApp.UIAxes.CameraPosition;
        end

        
        function cameraPathTable = generateCameraPath(app)
            switch app.TabGroup.SelectedTab.Title
                case 'Rotate'
                    switch app.DirectionDropDown.Value
                        case 'Clockwise'
                            tmp = linspace(app.AzimuthEditField.Value,app.AzimuthEditField.Value + app.DegreesEditField.Value,app.DegreesEditField.Value+1);
                        case 'Counter-Clockwise'
                            tmp = linspace(app.AzimuthEditField.Value,app.AzimuthEditField.Value - app.DegreesEditField.Value,app.DegreesEditField.Value+1);
                    end
                    tmp(end) = [];
                    azimuth = mod(tmp,360)';
                    elevation = repmat(app.ElevationEditField.Value,[numel(azimuth) 1]);
                case 'CustomPath'
                    switch app.PathStyleDropDown.Value
                        case 'Smoothed'
                            tablePoints = table2array(app.UITable.Data(:,[1,2]))';
                            if app.ReturntoStartCheckBox.Value
                                tablePoints = [tablePoints,tablePoints(:,1)];
                            end
                            tablePoints(1,:) = azimuthPathCorrection(app,tablePoints(1,:));
                            path = cscvn(tablePoints);
                            pathT = linspace(path.breaks(1),path.breaks(end),app.SamplesEditField.Value);
                            pathPoints = fnval(path,pathT)';
                        case 'Linear'
                    end
                    azimuth = pathPoints(:,1);
                    elevation = pathPoints(:,2);
            end
            cameraPathTable = table(azimuth,elevation);
        end

        function correctedAzimuthArray = azimuthPathCorrection(~,azimuthArray)
            azimuthArrayDiff = diff(azimuthArray);
            azimuthArrayDiff(azimuthArrayDiff<-180) = mod(azimuthArrayDiff(azimuthArrayDiff<-180),360);
            azimuthArrayDiff(azimuthArrayDiff>180) = azimuthArrayDiff(azimuthArrayDiff>180)-360;
            correctedAzimuthArray = [azimuthArray(1),azimuthArrayDiff];
            correctedAzimuthArray = cumsum(correctedAzimuthArray);
        end

    end

    methods (Access = public)
    
        function updateFields(app)
            app.AzimuthEditField.Value = app.MainApp.RotateParameters.Azimuth;
            app.ElevationEditField.Value = app.MainApp.RotateParameters.Elevation;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            if nargin==2
                app.MainApp = callingApp;
                app.ElevationEditField.Value = app.MainApp.RotateParameters.Elevation;
                app.AzimuthEditField.Value = app.MainApp.RotateParameters.Azimuth;
            end
        end

        % Value changed function: AzimuthEditField
        function AzimuthEditFieldValueChanged(app, event)
            rotateAxes(app)
        end

        % Value changed function: ElevationEditField
        function ElevationEditFieldValueChanged(app, event)
            rotateAxes(app)
        end

        % Button pushed function: GenerateMovieButton
        function GenerateMovieButtonPushed(app, event)
            formats = {'*.gif','GIF (*.gif)'};
            [filename,path] = uiputfile(formats,'Save GIF');
            if filename==0
                return
            end

            cameraPathTable = generateCameraPath(app);
            cameraPathLength = size(cameraPathTable,1);
            
            d = uiprogressdlg(app.UIFigure);
            F(cameraPathLength) = struct('cdata',[],'colormap',[]);
            for i = 1:cameraPathLength
                d.Value = i/cameraPathLength;
                d.Message = 'Collecting Images';
                app.AzimuthEditField.Value = cameraPathTable{i,1};
                app.ElevationEditField.Value = cameraPathTable{i,2};
                rotateAxes(app)
                F(i) = getframe(app.MainApp.UIFigure);
            end
            
            d.Indeterminate = 'on';
            d.Message = 'Writing Frames to GIF';
            for i = 1:numel(F)
                A = frame2im(F(i));
                [B,map] = rgb2ind(A,256);
                if i == 1
                    imwrite(B,map,fullfile(path,filename),'gif','LoopCount',Inf,'DelayTime',0.02);
                else
                    imwrite(B,map,fullfile(path,filename),'gif','WriteMode','append','DelayTime',0.02);
                end
            end
            close(d)
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            index = app.UITable.Selection; %Select Row
            tbl = table(app.AzimuthEditField.Value,app.ElevationEditField.Value,0,'VariableNames',{'Azimuth','Elevation','Zoom'});
            if isempty(index)
                app.UITable.Data = [app.UITable.Data;tbl];
            else
                tblA = app.UITable.Data(1:index(1),:);
                tblB = app.UITable.Data(index(1)+1:size(app.UITable.Data,1),:);
                app.UITable.Data = [tblA;tbl;tblB];
            end
        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
            index = app.UITable.Selection;
            if isempty(index)
                app.UITable.Data(size(app.UITable.Data,1),:) = [];
            else
                app.UITable.Data(index(1),:) = [];
            end
        end

        % Button pushed function: PreviewMovieButton
        function PreviewMovieButtonPushed(app, event)
            cameraPathTable = generateCameraPath(app);
            cameraPathLength = size(cameraPathTable,1);
            
            for i = 1:cameraPathLength
                app.AzimuthEditField.Value = cameraPathTable{i,1};
                app.ElevationEditField.Value = cameraPathTable{i,2};
                rotateAxes(app)
                pause(1/30)
            end
        end

        % Value changed function: ShowOrientationAxesCheckBox
        function ShowOrientationAxesCheckBoxValueChanged(app, event)
            for i = 1:length(app.MainApp.UIAxes2.Children)
                app.MainApp.UIAxes2.Children(i).Visible = app.ShowOrientationAxesCheckBox.Value;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            for i = 1:length(app.MainApp.UIAxes2.Children)
                app.MainApp.UIAxes2.Children(i).Visible = 1;
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'brain-24.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AzimuthEditFieldLabel = uilabel(app.UIFigure);
            app.AzimuthEditFieldLabel.Text = 'Azimuth';
            app.AzimuthEditField = uieditfield(app.UIFigure, 'numeric');
            app.AzimuthEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthEditFieldValueChanged, true);
            app.ElevationEditFieldLabel = uilabel(app.UIFigure);
            app.ElevationEditFieldLabel.Text = 'Elevation';
            app.ElevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElevationEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationEditFieldValueChanged, true);
            app.GenerateMovieButton = uibutton(app.UIFigure, 'push');
            app.GenerateMovieButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMovieButtonPushed, true);
            app.GenerateMovieButton.Text = 'Generate Movie';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RotateTab = uitab(app.TabGroup);
            app.RotateTab.Title = 'Rotate';
            app.DirectionDropDownLabel = uilabel(app.RotateTab);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.RotateTab);
            app.DirectionDropDown.Items = {'Clockwise', 'Counter-Clockwise'};
            app.DirectionDropDown.Value = 'Clockwise';
            app.DegreesEditFieldLabel = uilabel(app.RotateTab);
            app.DegreesEditFieldLabel.Text = 'Degrees';
            app.DegreesEditField = uieditfield(app.RotateTab, 'numeric');
            app.DegreesEditField.Limits = [0 360];
            app.DegreesEditField.Value = 360;
            app.CustomPathTab = uitab(app.TabGroup);
            app.CustomPathTab.Title = 'CustomPath';
            app.UITable = uitable(app.CustomPathTab);
            app.UITable.ColumnName = {'Azimuth'; 'Elevation'; 'Zoom'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.AddRowButton = uibutton(app.CustomPathTab, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = '+';
            app.DeleteRowButton = uibutton(app.CustomPathTab, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = '-';
            app.PathStyleDropDownLabel = uilabel(app.CustomPathTab);
            app.PathStyleDropDownLabel.Text = 'Path Style';
            app.PathStyleDropDown = uidropdown(app.CustomPathTab);
            app.PathStyleDropDown.Items = {'Smoothed', 'Linear'};
            app.PathStyleDropDown.Value = 'Smoothed';
            app.SamplesEditFieldLabel = uilabel(app.CustomPathTab);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.CustomPathTab, 'numeric');
            app.SamplesEditField.Limits = [1 Inf];
            app.SamplesEditField.Value = 100;
            app.ReturntoStartCheckBox = uicheckbox(app.CustomPathTab);
            app.ReturntoStartCheckBox.Text = 'Return to Start';
            app.ReturntoStartCheckBox.Value = true;
            app.PreviewMovieButton = uibutton(app.UIFigure, 'push');
            app.PreviewMovieButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewMovieButtonPushed, true);
            app.PreviewMovieButton.Text = 'Preview Movie';
            app.ShowOrientationAxesCheckBox = uicheckbox(app.UIFigure);
            app.ShowOrientationAxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowOrientationAxesCheckBoxValueChanged, true);
            app.ShowOrientationAxesCheckBox.Text = 'Show Orientation Axes';
            app.ShowOrientationAxesCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainViewer_MovieMaker(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end